classdef perturbationOnset_Copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        protocolEditField       matlab.ui.control.EditField
        protocolEditFieldLabel  matlab.ui.control.Label
        SaveButton              matlab.ui.control.Button
        TimemaxEditField        matlab.ui.control.NumericEditField
        TimemaxEditFieldLabel   matlab.ui.control.Label
        TimeminEditField        matlab.ui.control.NumericEditField
        TimeminEditFieldLabel   matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        NextButton              matlab.ui.control.Button
        PreviousButton          matlab.ui.control.Button
        OffsetEditField         matlab.ui.control.NumericEditField
        OffsetEditFieldLabel    matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        UIAxes3                 matlab.ui.control.UIAxes
        UIAxes2                 matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data_align % Description
        yes % Description
        sweep % Description
        offset % Description
        velocity % Description
        proto % Description
        no % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
             CTL = 1; CTL2 = 2; VER = 3; HOR = 4; unload = 6
             SOL = 1; TA = 2; ANG = 3; FSR = 4; time = 5; VEL = 6; ACC = 7;
             
             if app.sweep < numel(app.yes)
                app.sweep = app.sweep + 1; 
             end 
            
            offset = app.offset(app.yes(app.sweep)); 
            x_pos = app.data_align{app.proto,time}*10^3; % position x-axis
            y_pos = app.data_align{app.proto,ANG}(app.yes(app.sweep),:); % position y-axis
            y_pos_gns = mean(app.data_align{app.proto,ANG}(app.no,:)); % position y-axis

            x_vel = app.data_align{app.proto,time}(1:end-1)*10^3; % velocity x-axis
            y_vel = app.data_align{app.proto,VEL}(app.yes(app.sweep),:); % velocity y-axis
            
            x_sol = x_pos; 
            y_sol = app.data_align{app.proto,SOL}(app.yes(app.sweep),:);
            y_sol_no_gns = mean(app.data_align{app.proto,SOL}(app.no,:),1);

            x_min = app.TimeminEditField.Value; % plot limit min-value
            x_max = app.TimemaxEditField.Value; % plot limit max-value
            [~,idx_min]=min(abs(x_pos-x_min)); % plot limit index-value
            [~,idx_max]=min(abs(x_pos-x_max)); % plot limit index-value

            min_pos = min(y_pos(idx_min:idx_max));
            max_pos = max(y_pos(idx_min:idx_max));
            min_vel = min(y_vel(idx_min:idx_max));
            max_vel = max(y_vel(idx_min:idx_max));
            min_sol = min(y_sol(idx_min:idx_max));
            max_sol = max(y_sol(idx_min:idx_max));

            % Position
            plot(app.UIAxes, x_pos, y_pos, "LineWidth",1, "color","black")     
            title(app.UIAxes,"Sweep " + app.yes(app.sweep) + ". Perturbation number " + app.sweep); 
            hold(app.UIAxes, 'on')
            %plot(app.UIAxes, x_pos,  mean(app.data_align{app.proto,ANG}(app.yes,:),1), "LineWidth",1, "color","blue")     
            plot(app.UIAxes, x_pos,  mean(app.data_align{app.proto,ANG}(app.no,:),1), "LineWidth",3, "color",[0.75,0.75,0.75])     
            plot(app.UIAxes, [offset,offset], [min_pos, max_pos+0.5])
            hold(app.UIAxes, 'off')

            % Velocity 
            plot(app.UIAxes2, x_vel, y_vel, "LineWidth",1, "color","black")     
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, [offset,offset], [min_vel, max_vel])
            plot(app.UIAxes2, [x_min,x_max], [0, 0])
            hold(app.UIAxes2, 'off') 


            % Soleus 
            plot(app.UIAxes3, x_sol, y_sol, "LineWidth",1, "color","black")     
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3, x_sol, mean(app.data_align{app.proto,SOL}(app.no,:),1) , "LineWidth",1, "color",[0.75, 0.75, 0.75])     

           % plot(app.UIAxes3, x_sol, y_sol_no_gns, "LineWidth",3, "color",[0.75,0.75,0.75])     
            plot(app.UIAxes3, [offset,offset], [min_sol, max_sol])
            hold(app.UIAxes3, 'off') 

            app.OffsetEditField.Value = offset; 
            app.Slider.Value = offset;  

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            CTL = 1; VER = 2; HOR = 3; CTL2 = 4; time = 5; unload = 6; % THOMAS
            SOL = 1; TA = 2; ANG = 3; FSR = 4; time = 5; VEL = 6; ACC = 7;
            
            app.data_align = evalin('base','data_align');
            %app.velocity = evalin('base','velocity_align');
            temp = app.protocolEditField.Value; 

             if strcmp(temp ,"unload")
                app.proto = unload; 
                app.yes = evalin('base','unload_yes');
                app.no = evalin('base','unload_no');
                app.offset = evalin('base','offset_unload');
          
             elseif strcmp(temp ,"VER")
                app.proto = VER; 
                app.yes = evalin('base','VER_yes');
                app.no = evalin('base','VER_no');

                app.offset = evalin('base','offset_ver');

            elseif strcmp(temp ,"HOR")
                app.proto = HOR; 
                app.yes = evalin('base','HOR_yes');
                app.no = evalin('base','HOR_no');

                app.offset = evalin('base','offset_hor');

            end


            app.sweep = 1; 

            
            app.Slider.Value = app.offset(app.yes(app.sweep)); 
            xlim(app.UIAxes,[app.TimeminEditField.Value,  app.TimemaxEditField.Value]);
            xlim(app.UIAxes2,[app.TimeminEditField.Value,  app.TimemaxEditField.Value]);
            xlim(app.UIAxes3,[app.TimeminEditField.Value,  app.TimemaxEditField.Value]);

            app.Slider.Limits = [app.TimeminEditField.Value app.TimemaxEditField.Value];

            offset = app.offset(app.yes(app.sweep)); 
            x_pos = app.data_align{app.proto,time}*10^3; % position x-axis
            y_pos = app.data_align{app.proto,ANG}(app.yes(app.sweep),:); % position y-axis
            y_pos_gns = mean(app.data_align{app.proto,ANG}(app.no,:)); % position y-axis

            x_vel = app.data_align{app.proto,time}(1:end-1)*10^3; % velocity x-axis
            y_vel = app.data_align{app.proto,VEL}(app.yes(app.sweep),:); % velocity y-axis
            x_sol = x_pos; 
            y_sol = app.data_align{app.proto,SOL}(app.yes(app.sweep),:);

            x_min = app.TimeminEditField.Value; % plot limit min-value
            x_max = app.TimemaxEditField.Value; % plot limit max-value
            [~,idx_min]=min(abs(x_pos-x_min)); % plot limit index-value
            [~,idx_max]=min(abs(x_pos-x_max)); % plot limit index-value

            min_pos = min(y_pos(idx_min:idx_max));
            max_pos = max(y_pos(idx_min:idx_max));
            min_vel = min(y_vel(idx_min:idx_max));
            max_vel = max(y_vel(idx_min:idx_max));
            min_sol = min(y_sol(idx_min:idx_max));
            max_sol = max(y_sol(idx_min:idx_max));

            % Position
            plot(app.UIAxes, x_pos, y_pos, "LineWidth",1, "color","black")     
            title(app.UIAxes,"Sweep " + app.yes(app.sweep) + ". Perturbation number " + app.sweep); 
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, x_pos, y_pos_gns, "LineWidth",1, "color",[0.75,0.75,0.75])     
            plot(app.UIAxes, [offset,offset], [min_pos, max_pos+0.5])
            hold(app.UIAxes, 'off')

            % Velocity 
            plot(app.UIAxes2, x_vel, y_vel, "LineWidth",1, "color","black")     
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, [offset,offset], [min_vel, max_vel])
            plot(app.UIAxes2, [x_min,x_max], [0, 0])
            hold(app.UIAxes2, 'off') 

            % Soleus 
            plot(app.UIAxes3, x_sol, y_sol, "LineWidth",1, "color","black")     
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3, [offset,offset], [min_sol, max_sol])
            plot(app.UIAxes3, x_sol, mean(app.data_align{app.proto,SOL}(app.no,:),1) , "LineWidth",1, "color",[0.75, 0.75, 0.75])     

            hold(app.UIAxes3, 'off') 

            app.OffsetEditField.Value = offset; 
            app.Slider.Value = offset;  
    
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
             CTL = 1; VER = 2; HOR = 3; unload = 6 % Benedikte
             % CTL = 1; CTL2 = 2; VER = 3; HOR = 4; % THOMAS
             SOL = 1; TA = 2; ANG = 3; FSR = 4; time = 5; VEL = 6; ACC = 7;
             
             if app.sweep > 1
                app.sweep = app.sweep - 1; 
             end 
            
            offset = app.offset(app.yes(app.sweep)); 
            x_pos = app.data_align{app.proto,time}*10^3; % position x-axis
            y_pos = app.data_align{app.proto,ANG}(app.yes(app.sweep),:); % position y-axis
            y_pos_gns = mean(app.data_align{app.proto,ANG}(app.no,:)); % position y-axis
            x_vel = app.data_align{app.proto,time}(1:end-1)*10^3; % velocity x-axis
            y_vel = app.data_align{app.proto,VEL}(app.yes(app.sweep),:); % velocity y-axis
            x_sol = x_pos; 
            y_sol = app.data_align{app.proto,SOL}(app.yes(app.sweep),:);

            x_min = app.TimeminEditField.Value; % plot limit min-value
            x_max = app.TimemaxEditField.Value; % plot limit max-value
            [~,idx_min]=min(abs(x_pos-x_min)); % plot limit index-value
            [~,idx_max]=min(abs(x_pos-x_max)); % plot limit index-value

            min_pos = min(y_pos(idx_min:idx_max));
            max_pos = max(y_pos(idx_min:idx_max));
            min_vel = min(y_vel(idx_min:idx_max));
            max_vel = max(y_vel(idx_min:idx_max));
            min_sol = min(y_sol(idx_min:idx_max));
            max_sol = max(y_sol(idx_min:idx_max));

            % Position
            plot(app.UIAxes, x_pos, y_pos, "LineWidth",1, "color","black")     
            title(app.UIAxes,"Sweep " + app.yes(app.sweep) + ". Perturbation number " + app.sweep); 
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, x_pos, y_pos_gns, "LineWidth",1, "color",[0.75,0.75,0.75])     
            plot(app.UIAxes, [offset,offset], [min_pos, max_pos+0.5])
            hold(app.UIAxes, 'off')

            % Velocity 
            plot(app.UIAxes2, x_vel, y_vel, "LineWidth",1, "color","black")     
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, [offset,offset], [min_vel, max_vel])
            plot(app.UIAxes2, [x_min,x_max], [0, 0])
            hold(app.UIAxes2, 'off') 

            % Soleus 
            plot(app.UIAxes3, x_sol, y_sol, "LineWidth",1, "color","black")     
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3, [offset,offset], [min_sol, max_sol])
            plot(app.UIAxes3, x_sol, mean(app.data_align{app.proto,SOL}(app.no,:),1) , "LineWidth",1, "color",[0.75, 0.75, 0.75])     

            hold(app.UIAxes3, 'off') 

            app.OffsetEditField.Value = offset; 
            app.Slider.Value = offset;  


        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.offset(app.yes(app.sweep)) = event.Value;

            CTL = 1; VER = 2; HOR = 3; unload = 6 % Benedikte
            % CTL = 1; CTL2 = 2; VER = 3; HOR = 4; % THOMAS
             SOL = 1; TA = 2; ANG = 3; FSR = 4; time = 5; VEL = 6; ACC = 7;
            
            offset = app.offset(app.yes(app.sweep)); 
            x_pos = app.data_align{app.proto,time}*10^3; % position x-axis
            y_pos = app.data_align{app.proto,ANG}(app.yes(app.sweep),:); % position y-axis
            y_pos_gns = mean(app.data_align{app.proto,ANG}(app.no,:)); % position y-axis

            x_vel = app.data_align{app.proto,time}(1:end-1)*10^3; % velocity x-axis
            y_vel = app.data_align{app.proto,VEL}(app.yes(app.sweep),:); % velocity y-axis
            x_sol = x_pos; 
            y_sol = app.data_align{app.proto,SOL}(app.yes(app.sweep),:);

            x_min = app.TimeminEditField.Value; % plot limit min-value
            x_max = app.TimemaxEditField.Value; % plot limit max-value
            [~,idx_min]=min(abs(x_pos-x_min)); % plot limit index-value
            [~,idx_max]=min(abs(x_pos-x_max)); % plot limit index-value

            min_pos = min(y_pos(idx_min:idx_max));
            max_pos = max(y_pos(idx_min:idx_max));
            min_vel = min(y_vel(idx_min:idx_max));
            max_vel = max(y_vel(idx_min:idx_max));
            min_sol = min(y_sol(idx_min:idx_max));
            max_sol = max(y_sol(idx_min:idx_max));

            % Position
            plot(app.UIAxes, x_pos, y_pos, "LineWidth",1, "color","black")     
            title(app.UIAxes,"Sweep " + app.yes(app.sweep) + ". Perturbation number " + app.sweep); 
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, x_pos, y_pos_gns, "LineWidth",1, "color",[0.75,0.75,0.75])     

            plot(app.UIAxes, [offset,offset], [min_pos, max_pos+0.5])
            hold(app.UIAxes, 'off')

            % Velocity 
            plot(app.UIAxes2, x_vel, y_vel, "LineWidth",1, "color","black")     
            hold(app.UIAxes2, 'on')
            plot(app.UIAxes2, [offset,offset], [min_vel, max_vel])
            plot(app.UIAxes2, [x_min,x_max], [0, 0])
            hold(app.UIAxes2, 'off') 

            SLR_x = [offset+39 offset+59 offset+59 offset+39];
            y = [-min_sol -min_sol max_sol max_sol];


            % Soleus 
            plot(app.UIAxes3, x_sol, y_sol, "LineWidth",1, "color","black")     
            hold(app.UIAxes3, 'on')
            plot(app.UIAxes3, [offset,offset], [min_sol, max_sol])
            patch(app.UIAxes3,SLR_x,y,[0.75, 0.75, 0.75], 'EdgeColor', 'none','FaceAlpha',0.3)
            plot(app.UIAxes3, x_sol, mean(app.data_align{app.proto,SOL}(app.no,:),1) , "LineWidth",1, "color",[0.75, 0.75, 0.75])     

            hold(app.UIAxes3, 'off') 


            app.OffsetEditField.Value = offset;

        end

        % Value changed function: OffsetEditField
        function OffsetEditFieldValueChanged(app, event)
            offsetValue = app.OffsetEditField.Value;
            
            app.Slider.Value = offsetValue;  


        end

        % Value changed function: TimeminEditField
        function TimeminEditFieldValueChanged(app, event)
            xmin = app.TimeminEditField.Value;
            xlim(app.UIAxes, [xmin, app.TimemaxEditField.Value])
            xlim(app.UIAxes2, [xmin, app.TimemaxEditField.Value])
            xlim(app.UIAxes3, [xmin, app.TimemaxEditField.Value])

            app.Slider.Limits = [app.TimeminEditField.Value app.TimemaxEditField.Value];

            
        end

        % Value changed function: TimemaxEditField
        function TimemaxEditFieldValueChanged(app, event)
            xmax = app.TimemaxEditField.Value;
            xlim(app.UIAxes, [app.TimeminEditField.Value, xmax])
            xlim(app.UIAxes2, [app.TimeminEditField.Value, xmax])
            xlim(app.UIAxes3, [app.TimeminEditField.Value, xmax])

            app.Slider.Limits = [app.TimeminEditField.Value app.TimemaxEditField.Value];


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            assignin('base','offset',app.offset)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'time [ms]')
            ylabel(app.UIAxes, 'Position [Deg]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Time [ms]')
            ylabel(app.UIAxes2, 'Velocity [Deg/s]')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'time [ms]')
            ylabel(app.UIAxes3, 'SOL EMG [\muV]')
            zlabel(app.UIAxes3, 'Z')
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-50 50];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.OffsetEditFieldLabel = uilabel(app.UIFigure);
            app.OffsetEditFieldLabel.Text = 'Offset';
            app.OffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetEditFieldValueChanged, true);
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TimeminEditFieldLabel = uilabel(app.UIFigure);
            app.TimeminEditFieldLabel.Text = 'Time min';
            app.TimeminEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeminEditField.ValueChangedFcn = createCallbackFcn(app, @TimeminEditFieldValueChanged, true);
            app.TimeminEditField.Value = -50;
            app.TimemaxEditFieldLabel = uilabel(app.UIFigure);
            app.TimemaxEditFieldLabel.Text = 'Time max';
            app.TimemaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimemaxEditField.ValueChangedFcn = createCallbackFcn(app, @TimemaxEditFieldValueChanged, true);
            app.TimemaxEditField.Value = 200;
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.protocolEditFieldLabel = uilabel(app.UIFigure);
            app.protocolEditFieldLabel.Text = 'protocol';
            app.protocolEditField = uieditfield(app.UIFigure, 'text');
            app.protocolEditField.Value = 'VER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perturbationOnset_Copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end